classdef HW1_m11415015 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        FocusTab               matlab.ui.container.Tab
        AreaSelectionButton    matlab.ui.control.Button
        DepthSlider            matlab.ui.control.Slider
        SliderLabel            matlab.ui.control.Label
        Gauge                  matlab.ui.control.LinearGauge
        GaugeLabel             matlab.ui.control.Label
        AutoFocusCheckBox      matlab.ui.control.CheckBox
        M11415015Label         matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        UIAxes                 matlab.ui.control.UIAxes
        ColorTab               matlab.ui.container.Tab
        OptionDropDown         matlab.ui.control.DropDown
        DropDownLabel          matlab.ui.control.Label
        AreaSelectionButton_2  matlab.ui.control.Button
        Image2                 matlab.ui.control.Image
        UIAxes2                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ccimg    % ColorChecker 影像（double 格式）
        ccMat    % 3×3 色彩校正矩陣
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AreaSelectionButton.Visible  = 'off';
            app.Image.Visible                = 'off';
            app.OptionDropDown.Visible       = 'off';
            app.Image2.Visible               = 'off';
            app.OptionDropDown.ItemsData = num2cell(1:3);
            app.OptionDropDown.Items     = {'RAW, G=1', 'Color Correction, G=1', 'Color Correction G=0.5'};
            app.OptionDropDown.Value     = 3;
            app.UIAxes.Toolbar.Visible  = 'off';
            app.UIAxes2.Toolbar.Visible = 'off';
            app.UIAxes.XLabel.String    = ' ';
            app.UIAxes.YLabel.String    = ' ';
            app.UIAxes2.XLabel.String   = ' ';
            app.UIAxes2.YLabel.String   = ' ';
            app.Gauge.Limits       = [1 11];
            app.DepthSlider.Limits = [1 11];
            app.DepthSlider.Value  = 1;
            rawImg = imread('cc1.jpg');
            app.ccimg = double(rawImg) / 255;
            app.ccMat = eye(3);
            DepthSliderValueChanged(app, struct());
            app.AutoFocusCheckBox.Text = 'Auto Focus';
            % --- Focus 頁籤元件位置 ---
            app.UIAxes.Position              = [5,   5,  355, 440];
            app.Gauge.Position               = [370,  45,  35, 340];
            app.GaugeLabel.Position          = [370,  10,  50,  22];
            app.DepthSlider.Position         = [425,  45,   3, 340];
            app.SliderLabel.Position         = [410,  10,  50,  22];
            app.Image.Position               = [470, 120, 160, 200];
            app.AutoFocusCheckBox.Position   = [370, 430, 110,  22];
            app.M11415015Label.Position      = [490, 430, 145,  22];
            app.AreaSelectionButton.Position = [370, 390, 120,  35];
            % --- Color 頁籤元件位置 ---
            app.UIAxes2.Position               = [5,   5,  355, 440];
            app.Image2.Position                = [370, 80,  260, 300];
            app.AreaSelectionButton_2.Position = [370, 420, 120,  35];
            app.DropDownLabel.Position   = [350 355 50 22];
            app.OptionDropDown.Position  = [410 355 180 22];
        end

        % Value changed function: DepthSlider
        function DepthSliderValueChanged(app, event)
            idx = round(app.DepthSlider.Value);
            idx = max(1, min(11, idx));
            img = imread(sprintf('e%d.jpg', idx));
            imshow(img, 'Parent', app.UIAxes);
            app.Gauge.Value = idx;
        end

        % Value changed function: AutoFocusCheckBox
        function AutoFocusCheckBoxValueChanged(app, event)
            if app.AutoFocusCheckBox.Value
                app.AreaSelectionButton.Visible = 'on';
                app.DepthSlider.Visible         = 'off';
            else
                app.AreaSelectionButton.Visible = 'off';
                app.Image.Visible               = 'off';
                app.DepthSlider.Visible         = 'on';
            end
        end

        % Button pushed function: AreaSelectionButton
        function AreaSelectionButtonPushed(app, event)
            % --- 讀取 11 張影像 ---
            imgs = cell(1, 11);
            for i = 1:11
                rawImg = imread(sprintf('e%d.jpg', i));
                imgs{i} = double(rawImg) / 255;
            end

            % --- 開暫時視窗，顯示第 1 張影像 ---
            fig_temp = figure('Name', '自動對焦 - 選取區域', 'NumberTitle', 'off');
            ax_temp  = axes(fig_temp);
            [imgH, imgW, ~] = size(imgs{1});
            image(ax_temp, uint8(imgs{1} * 255));
            axis(ax_temp, 'image');
            set(ax_temp, 'XTick', [], 'YTick', []);
            title(ax_temp, '請用滑鼠拖曳框選對焦區域');

            % --- 拖曳框選（rbbox = base MATLAB 橡皮筋矩形）---
            waitforbuttonpress;
            point1 = get(ax_temp, 'CurrentPoint');
            rbbox;
            point2 = get(ax_temp, 'CurrentPoint');
            close(fig_temp);

            % --- 計算 ROI 範圍 ---
            x1 = round(min(point1(1,1), point2(1,1)));
            y1 = round(min(point1(1,2), point2(1,2)));
            x2 = round(max(point1(1,1), point2(1,1)));
            y2 = round(max(point1(1,2), point2(1,2)));
            x1 = max(1, min(imgW, x1));
            y1 = max(1, min(imgH, y1));
            x2 = max(1, min(imgW, x2));
            y2 = max(1, min(imgH, y2));
            if x2 <= x1 || y2 <= y1
                return;
            end

            % --- Sobel 邊緣清晰度 ---
            sobelX = [-1 0 1; -2 0 2; -1 0 1] / 8;
            sobelY = sobelX';
            edgeRatios = zeros(1, 11);
            for i = 1:11
                crop    = imgs{i}(y1:y2, x1:x2, :);
                grayROI = rgb2gray(crop);
                gx      = conv2(grayROI, sobelX, 'same');
                gy      = conv2(grayROI, sobelY, 'same');
                edgeMag = sqrt(gx.^2 + gy.^2);
                edgeRatios(i) = mean(edgeMag(:));
            end

            [~, bestIdx] = max(edgeRatios);

            % --- 顯示結果 ---
            imshow(imgs{bestIdx}, 'Parent', app.UIAxes);
            title(app.UIAxes, sprintf('最佳對焦：e%d.jpg', bestIdx));
            roiImg = imgs{bestIdx}(y1:y2, x1:x2, :);
            app.Image.ImageSource = roiImg;
            app.Image.Visible     = 'on';
            app.Gauge.Value = bestIdx;
        end

        % Button pushed function: AreaSelectionButton_2
        function AreaSelectionButton_2Pushed(app, event)
            % --- 開暫時視窗，顯示 RAW 影像 ---
            fig_temp = figure('Name', '色彩校正 - 選取 ColorChecker', 'NumberTitle', 'off');
            ax_temp  = axes(fig_temp);
            image(ax_temp, uint8(app.ccimg * 255));
            axis(ax_temp, 'image');
            set(ax_temp, 'XTick', [], 'YTick', []);

            % --- 逐點點擊 + 即時標記 ---
            srcPoints = zeros(4, 2);
            cornerNames = {'左上', '右上', '右下', '左下'};
            hold(ax_temp, 'on');
            for k = 1:4
                title(ax_temp, sprintf('請點選第 %d 點（%s）', k, cornerNames{k}));
                [px, py] = ginput(1);
                srcPoints(k, :) = [px, py];
                plot(ax_temp, px, py, 'ro', 'MarkerSize', 10, 'LineWidth', 2);
                text(ax_temp, px+5, py-5, num2str(k), 'Color', 'r', 'FontSize', 14, 'FontWeight', 'bold');
                if k > 1
                    plot(ax_temp, srcPoints(k-1:k, 1), srcPoints(k-1:k, 2), 'r-', 'LineWidth', 2);
                end
                if k == 4
                    plot(ax_temp, srcPoints([4 1], 1), srcPoints([4 1], 2), 'r-', 'LineWidth', 2);
                end
            end
            hold(ax_temp, 'off');
            pause(0.5);
            close(fig_temp);

            % --- 透視校正 ---
            targetW  = 600;
            targetH  = 400;
            dstPoints = [1 1; targetW 1; targetW targetH; 1 targetH];
            nPts = size(srcPoints, 1);
            A = zeros(2*nPts, 9);
            for k = 1:nPts
                xs = srcPoints(k,1); ys = srcPoints(k,2);
                xd = dstPoints(k,1); yd = dstPoints(k,2);
                A(2*k-1,:) = [xs ys 1  0  0  0  -xd*xs -xd*ys -xd];
                A(2*k,  :) = [ 0  0 0  xs ys 1  -yd*xs -yd*ys -yd];
            end
            [~, ~, V] = svd(A);
            H = reshape(V(:,end), 3, 3)';
            Hinv = H \ eye(3);
            [dxGrid, dyGrid] = meshgrid(1:targetW, 1:targetH);
            dstCoords = [dxGrid(:)'; dyGrid(:)'; ones(1, targetW*targetH)];
            srcCoords = Hinv * dstCoords;
            sx = reshape(srcCoords(1,:) ./ srcCoords(3,:), targetH, targetW);
            sy = reshape(srcCoords(2,:) ./ srcCoords(3,:), targetH, targetW);
            ccRect = zeros(targetH, targetW, 3);
            for ch = 1:3
                ccRect(:,:,ch) = interp2(app.ccimg(:,:,ch), sx, sy, 'linear', 0);
            end

            imshow(ccRect, 'Parent', app.UIAxes2);
            title(app.UIAxes2, '透視校正後的 ColorChecker');
            % --- 讀取 24 色塊 ---
            rows = 4; cols = 6;
            blockH = floor(targetH / rows);
            blockW = floor(targetW / cols);
            RGB_values = zeros(rows * cols, 3);
            pIdx = 1;
            for r = 1:rows
                for c = 1:cols
                    xS = (c-1) * blockW + 1;
                    yS = (r-1) * blockH + 1;
                    block = ccRect(yS:yS+blockH-1, xS:xS+blockW-1, :);
                    RGB_values(pIdx, :) = squeeze(mean(mean(block, 1), 2));
                    pIdx = pIdx + 1;
                end
            end
            data       = load('RGB_target.mat');
            fn         = fieldnames(data);
            RGB_target = data.(fn{1});
            if rank(RGB_values) < 3
                title(app.UIAxes2, 'RGB_values 奇異，無法計算');
                return;
            end
            app.ccMat = RGB_values \ RGB_target;
            app.OptionDropDown.Visible = 'on';
            app.Image2.Visible         = 'on';
            OptionDropDownValueChanged(app, []);
        end

        % Value changed function: OptionDropDown
        function OptionDropDownValueChanged(app, event)
            val = app.OptionDropDown.Value;
            switch val
                case 1
                    M           = eye(3);
                    gamma_value = 1;
                case 2
                    M           = app.ccMat;
                    gamma_value = 1;
                case 3
                    M           = app.ccMat;
                    gamma_value = 0.5;
                otherwise
                    return;
            end
            [imgH, imgW, ~] = size(app.ccimg);
            ccimg2d = reshape(app.ccimg, [], 3);
            out2d   = ccimg2d * M;
            out     = reshape(out2d, imgH, imgW, 3);
            out     = max(0, min(1, out));
            out     = out .^ gamma_value;
            app.Image2.ImageSource = out;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FocusTab = uitab(app.TabGroup);
            app.FocusTab.Title = 'Focus';
            app.UIAxes = uiaxes(app.FocusTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.FocusTab);
            app.M11415015Label = uilabel(app.FocusTab);
            app.M11415015Label.Text = 'M11415015';
            app.AutoFocusCheckBox = uicheckbox(app.FocusTab);
            app.AutoFocusCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoFocusCheckBoxValueChanged, true);
            app.GaugeLabel = uilabel(app.FocusTab);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.FocusTab, 'linear');
            app.SliderLabel = uilabel(app.FocusTab);
            app.SliderLabel.Text = 'Slider';
            app.DepthSlider = uislider(app.FocusTab);
            app.DepthSlider.ValueChangedFcn = createCallbackFcn(app, @DepthSliderValueChanged, true);
            app.AreaSelectionButton = uibutton(app.FocusTab, 'push');
            app.AreaSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @AreaSelectionButtonPushed, true);
            app.AreaSelectionButton.Text = 'Area Selection';
            app.ColorTab = uitab(app.TabGroup);
            app.ColorTab.Title = 'Color';
            app.UIAxes2 = uiaxes(app.ColorTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Image2 = uiimage(app.ColorTab);
            app.AreaSelectionButton_2 = uibutton(app.ColorTab, 'push');
            app.AreaSelectionButton_2.ButtonPushedFcn = createCallbackFcn(app, @AreaSelectionButton_2Pushed, true);
            app.AreaSelectionButton_2.Text = 'Area Selection';
            app.DropDownLabel = uilabel(app.ColorTab);
            app.DropDownLabel.Text = 'Drop Down';
            app.OptionDropDown = uidropdown(app.ColorTab);
            app.OptionDropDown.ValueChangedFcn = createCallbackFcn(app, @OptionDropDownValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HW1_m11415015

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end