classdef HW2_M11415015 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        CutMenu           matlab.ui.container.Menu
        OpenMenu          matlab.ui.container.Menu
        SaveMenu          matlab.ui.container.Menu
        CloseMenu         matlab.ui.container.Menu
        ExitMenu          matlab.ui.container.Menu
        MergeMenu         matlab.ui.container.Menu
        OpenMenu_2        matlab.ui.container.Menu
        CloseMenu_2       matlab.ui.container.Menu
        ExitMenu_2        matlab.ui.container.Menu
        WatermarkMenu     matlab.ui.container.Menu
        OutputmergeLabel  matlab.ui.control.Label
        UITable           matlab.ui.control.Table
        Button_2          matlab.ui.control.StateButton
        Button            matlab.ui.control.StateButton
        RangeLabel        matlab.ui.control.Label
        RangeSlider       matlab.ui.control.RangeSlider
        SliderLabel       matlab.ui.control.Label
        LUTsListBox       matlab.ui.control.ListBox
        ListBoxLabel      matlab.ui.control.Label
        LUTsLabel         matlab.ui.control.Label
        Image2            matlab.ui.control.Image
        Image             matlab.ui.control.Image
    end

    
    properties (Access = private)
        v1          % VideoReader 物件
        im          % 當下影格
        lut         % 3D 色調對照表
        lutSize     % LUT 格數
        t           % 待合併表列
        watermark = {}  % 浮水印預設參數
        watermarkImage  % 快取渲染好的浮水印圖
        summaryFrames   % 10格摘要影格（加分用）
        summaryDur      % 摘要對應的影片總長（加分用）
        mergeFile = 'merge.mp4'
    end
    
    methods (Access = private)
        
        function [lut, lutSize] = readCubeFile(app, cubeFile)
            % 定義一個函式 readCubeFile
            % 輸入：
            %   app      ：App Designer 物件本身，這裡沒有直接使用，但在 App 方法中通常會保留
            %   cubeFile ：.cube 檔案的路徑
            % 輸出：
            %   lut      ：讀取後的 3D LUT 資料
            %   lutSize  ：LUT 的尺寸，例如 17、33、64
            fid = fopen(cubeFile, 'r');
            % 以唯讀模式開啟 cubeFile 檔案
            % fid 是檔案識別碼，後面會用它來逐行讀取檔案
            lut = []; 
            % 初始化 LUT 資料為空矩陣
            % 後面會把每一行 RGB 數值逐步加入 lut
            lutSize = 0;
            % 初始化 LUT 尺寸為 0
            % 等讀到 LUT_3D_SIZE 這一行時才會更新
            
            while ~feof(fid)
                % 當尚未讀到檔案結尾時，持續執行迴圈
                line = fgetl(fid);
                % 從檔案中讀取一整行文字
                if ~ischar(line), break; end
                % 如果讀到的內容不是字元，代表可能讀取失敗或到達結尾
                % 此時跳出 while 迴圈
                line = strtrim(line);
                % 去除該行前後的空白字元，方便後續判斷
                if startsWith(line, 'LUT_3D_SIZE')
                % 如果這一行是 LUT_3D_SIZE 開頭
                % 代表這一行記錄了 3D LUT 的大小
                    lutSize = sscanf(line, 'LUT_3D_SIZE %d');
                    % 從該行讀取 LUT 的尺寸數值
                elseif isempty(line) || startsWith(line, '#')
                    % 如果這一行是空行，或是以 # 開頭的註解行
                    continue;
                else
                    values = sscanf(line, '%f %f %f');
                    % 從該行讀取三個浮點數
                    if numel(values) == 3
                    % 確認這一行真的成功讀到三個數值
                        lut = [lut; values'];
                        % 將 values 轉成橫列後，加到 lut 的下一列
                        % values 原本是 3x1，values' 會變成 1x3
                        % 最後 lut 會逐漸累積成 N x 3 的矩陣
                    end
                end
            end                  
            fclose(fid);    
            % 關閉檔案
            % 注意：這行必須放在 while 外面
            % 否則讀第一行後檔案就被關閉，後面會無法繼續讀取
            lut = reshape(lut, [lutSize, lutSize, lutSize, 3]);
            % 將原本 N x 3 的 LUT 資料重新整理成 4 維矩陣
            % 前三個維度代表 3D LUT 的座標
            % 第四個維度代表 RGB 三個通道
        end
        
        function imgOut = applyLUT(app, img, lut, lutSize)
            % 定義函式 applyLUT
            % 輸入：
            %   app      ：App 物件
            %   img      ：輸入影像
            %   lut      ：3D LUT 資料
            %   lutSize  ：LUT 尺寸 (例如 33)
            % 輸出：
            %   imgOut   ：套用 LUT 後的影像
            img = (img(:,:,1:3));
            % 只取影像的 RGB 三個通道
            % 避免有 alpha channel (RGBA) 造成問題
            if ~isa(img, 'double')
            % 如果影像不是 double 型態 (通常是 uint8)
                img = double(img) / 255;
                % 轉成 double 並正規化到 [0,1]
            end
            imgScaled = (img) * (lutSize - 1) + 1;
            % 將影像的值從 [0,1] 映射到 LUT 的索引範圍 [1, lutSize]
            % 因為 MATLAB 的索引是從 1 開始
            imgOut = zeros(size(img));
            % 初始化輸出影像 (與原圖同尺寸)
            for c = 1:3
            % 針對 RGB 三個通道逐一處理
                imgOut(:,:,c) = interp3( ...
                    1:lutSize, 1:lutSize, 1:lutSize, ...
                    lut(:,:,:,c), ...
                    imgScaled(:,:,2), imgScaled(:,:,1), imgScaled(:,:,3), ...
                    'linear', 0);
                % 使用三維插值 interp3
                %
                % 參數說明：
                % 1:lutSize (x, y, z 軸的座標範圍)
                % lut(:,:,:,c) → 第 c 個通道的 3D LUT
                %
                % imgScaled(:,:,2), imgScaled(:,:,1), imgScaled(:,:,3)
                % → 注意這裡順序：
                %   R ↔ 第2維 (y)
                %   G ↔ 第1維 (x)
                %   B ↔ 第3維 (z)
                %
                % 'linear' → 使用線性插值（平滑過渡）
                % 0 → 若超出範圍，填 0
            end
            imgOut = max(0, min(1, imgOut));
        end

        function loadSelectedLUT(app)
        % 從 UI 的 ListBox 讀取使用者選擇的 LUT
            lutName = app.LUTsListBox.Value;
            % 取得目前選擇的 LUT 檔名
            if isempty(lutName), return; end
            % 如果沒有選任何 LUT，直接結束函式
                cubeFile = fullfile('LUTs', lutName);
                % 組合完整路徑
            try
                [app.lut, app.lutSize] = readCubeFile(app, cubeFile);
                % 呼叫前面寫的 readCubeFile
                % 將 LUT 資料與大小存到 app 裡面
            catch
                uialert(app.UIFigure, '無法讀取 LUT 檔案', 'Error');
                % 如果讀檔失敗，顯示錯誤視窗提示使用者
            end
        end

        function makeVideoSummary(app, vr, imgComp)
            % 定義函式 makeVideoSummary
            % 功能：從影片中擷取 10 張影格，拼接成一張摘要圖（像時間軸預覽）
            %
            % 輸入：
            %   app     ：App 物件（用來儲存結果）
            %   vr      ：VideoReader 物件（影片讀取器）
            %   imgComp ：UI Image 元件（用來顯示摘要圖）
            dur = vr.Duration;
            % 取得影片總長度（秒）
            times = linspace(0, dur, 10);
            % 在影片時間範圍內平均取 10 個時間點
            frames = cell(1, 10);
            % 建立 cell array 用來存 10 張影格
            for i = 1:10
                % 依序讀取 10 個時間點的影格
                vr.CurrentTime = min(times(i), dur - 0.001);
                % 設定影片讀取位置到指定時間點
                % 使用 min(..., dur - 0.001) 是為了避免剛好讀到影片結尾造成錯誤
                if hasFrame(vr)
                % 檢查該時間點是否還有可讀取的影格
                    frames{i} = readFrame(vr);
                    % 讀取該時間點的影格（回傳 RGB 影像）
                else
                    frames{i} = zeros(vr.Height, vr.Width, 3, 'uint8');
                    % 如果沒有影格（例如接近結尾），建立一張黑色影像替代（避免出錯)
                end
            end
            summary = cat(2, frames{:});
            % 將 10 張影格「水平拼接」（沿第 2 維）
            % 結果會是一張很寬的圖片（時間從左到右）
            imgComp.ImageSource = summary;
            % 將拼接後的摘要圖顯示在 UI 的 Image 元件上
            app.summaryFrames = frames;
            % 把 10 張影格存到 app 中（之後可以再用）
            app.summaryDur = dur;
            % 儲存影片總長度（方便後續計算或同步）
        end

        function frame = addWatermark(app, frame)
            % 定義函式 addWatermark
            % 功能：將浮水印貼到影像 frame 上
            %
            % 輸入：
            %   app   ：App 物件（包含 watermark 設定）
            %   frame ：原始影像
            % 輸出：
            %   frame ：加上浮水印後的影像
            if isempty(app.watermark), return; end
            % 如果沒有設定浮水印（文字或參數），直接回傳原圖
            if isempty(app.watermarkImage)
                % 如果尚未生成浮水印圖片
                app.watermarkImage = renderWatermarkImage(app);
                % 呼叫函式生成浮水印影像（文字轉圖片）
            end
            wm = double(app.watermarkImage) / 255;
            % 將浮水印影像轉為 double 並正規化到 [0,1]
            [wH, wW, ~] = size(wm);
            % 取得浮水印影像的高度與寬度
            [fH, fW, ~] = size(frame);
            % 取得原始影像的高度與寬度
        
            if wH > fH - 10 || wW > fW - 10, return; end
            % 如果浮水印太大（放不進去），直接不加浮水印
            frame(10:9+wH, 10:9+wW, :) = wm;
            % 將浮水印貼到影像左上角（偏移 10 pixel）
            % 位置：
            %   y = 10 ~ 9+wH
            %   x = 10 ~ 9+wW
            % 直接覆蓋原像素（沒有透明混合）
        end
        
        function wmImg = renderWatermarkImage(app)     
            % 定義函式 renderWatermarkImage
            % 功能：將浮水印文字轉成影像（RGB 圖片）
            wmText = app.watermark{1};
            % 取得浮水印文字（例如 "My Video"）
            fontSize = str2double(app.watermark{2});
            % 取得字體大小（字串轉數字）
            if isnan(fontSize), fontSize = 20; end
            % 如果轉換失敗（不是數字），預設為 20

            padding = 8;
            % 設定文字周圍的邊距
            approxW = max(100, round(fontSize * 0.75 * strlength(wmText) + 2*padding));
            % 粗略估算浮水印圖片的寬度
            % 0.75 是字寬比例（經驗值）
            approxH = round(fontSize * 1.6 + 2*padding);
            % 粗略估算高度（字高 + padding）

            fig = figure('Visible','off','Units','pixels', ...
                'Position',[0 0 approxW approxH], ...
                'Color','yellow','MenuBar','none','ToolBar','none');
            % 建立一個不可見的 figure（用來畫文字）
            % 背景顏色設為黃色（之後可當 key color）
            ax = axes(fig,'Units','normalized','Position',[0 0 1 1], ...
                'Color','yellow','XColor','none','YColor','none');
                % 建立 axes，填滿整個 figure
                % 同樣設定背景為黃色，並隱藏座標軸
                 xlim(ax,[0 1]); ylim(ax,[0 1]);
                 % 設定座標範圍（標準化座標）
                 text(ax, 0.5, 0.5, char(wmText), ...
                'FontSize', fontSize, ...
                'Color', 'black', ...
                'FontWeight', 'bold', ...
                'HorizontalAlignment','center', ...
                'VerticalAlignment','middle');
                % 在畫面中央繪製文字
                % 黑色、粗體、置中對齊
            drawnow;
            % 強制 MATLAB 立即更新圖像（確保文字被畫出來）
            F = getframe(ax);
            % 擷取 axes 畫面，轉成影像（RGB）
            close(fig);
            % 關閉 figure（釋放資源）
            wmImg = F.cdata;
            % 取得影像資料（uint8 RGB），作為浮水印圖片
        end

        function mergeVideos(app)
            % 定義函式 mergeVideos
            % 功能：將 app.t 表格中的多個影片依序合併成一支 mp4 影片
            outFile = 'merge.mp4';

            % 檔案存在就先試著刪掉（釋放舊 handle）
            if exist(outFile, 'file') == 2
                try
                    delete(outFile);
                    % 嘗試刪除舊的 merge.mp4
                    % 避免 VideoWriter 寫入時被舊檔案卡住
                catch
                    % 刪不掉 → 改用時間戳檔名
                    outFile = sprintf('merge_%s.mp4', datestr(now, 'HHMMSS'));
                    % 改用目前時間產生新的檔名
                    % 例如 merge_153022.mp4
                end
            end

            vOut = [];
            % 初始化輸出影片物件
            % 先設為空，方便 catch 裡判斷是否需要關閉
            try
                firstVR = VideoReader(char(app.t.Filename(1)));
                % 讀取第一支影片
                % 用它作為輸出影片的基準尺寸與幀率

                W = firstVR.Width; H = firstVR.Height;
                % 取得第一支影片的寬度、高度
    
                vOut = VideoWriter(outFile, 'MPEG-4');
                % 建立 VideoWriter 物件
                % 輸出格式為 MPEG-4，也就是 mp4
                vOut.FrameRate = firstVR.FrameRate;
                % 設定輸出影片的幀率
                % 使用第一支影片的 FrameRate 作為基準
                open(vOut);
                % 開啟輸出影片，準備開始寫入 frame

                for i = 1:height(app.t)
                    % 依序處理 app.t 表格中的每一支影片
                    vr = VideoReader(char(app.t.Filename(i)));
                    % 建立目前第 i 支影片的 VideoReader
                    while hasFrame(vr)
                        % 只要目前影片還有 frame，就持續讀取
                        try
                            frame = readFrame(vr);
                            % 讀取目前影片的一個 frame
                        catch
                            break;
                            % 如果讀取 frame 時發生錯誤，跳出 while，停止讀這支影片
                        end
                        if size(frame,2) ~= W || size(frame,1) ~= H
                            % 如果目前 frame 的寬或高和第一支影片不同
                            frame = imresize(frame, [H, W]);
                            % 將 frame 縮放成第一支影片的尺寸
                            % 注意 imresize 的格式是 [高度, 寬度]
                        end
                        writeVideo(vOut, frame);
                        % 將目前 frame 寫入輸出影片
                    end
                end
                close(vOut);
            catch ME
                % 如果合併過程中發生任何錯誤
                if ~isempty(vOut), try close(vOut); catch; end, end
                % 如果 vOut 已經被建立，就嘗試關閉它，避免輸出檔案被鎖住或損壞
                uialert(app.UIFigure, ['Merge failed: ' ME.message], 'Error');
                % 顯示錯誤視窗，告訴使用者合併失敗原因
                return;
                % 結束函式，不繼續往下執行
            end

            app.mergeFile = outFile;    
            % 將實際輸出的檔名存到 app.mergeFile
            % 因為檔名可能是 merge.mp4，也可能是時間戳版本
            app.OutputmergeLabel.Text = ['Output: ' outFile];
            % 更新 UI Label，顯示輸出檔案名稱
        
            % 製作合併後視訊摘要
            vMerge = VideoReader(outFile);    
            % 重新讀取剛剛輸出的合併影片
            makeVideoSummary(app, vMerge, app.Image2);
            % 呼叫 makeVideoSummary
            % 從合併後影片中擷取 10 張影格並顯示在 app.Image2
        end
        
        function highlightSummaryFrame(app, currentTime, totalDur, range)
            % 定義函式 highlightSummaryFrame
            % 功能：在影片摘要圖中標示目前播放位置，並將指定範圍外的縮圖塗黑
            if isempty(app.summaryFrames), return; end
            % 如果沒有影片摘要影格，直接結束函式
            if nargin < 4, range = [0, totalDur]; end
            % 如果沒有傳入 range 參數，預設範圍就是整部影片
            % range = [開始時間, 結束時間]
    
            summary = cat(2, app.summaryFrames{:});
            % 將 app.summaryFrames 裡的 10 張影格水平拼接成一張摘要圖
            H = size(app.summaryFrames{1}, 1);
            % 取得單張摘要影格的高度
            W = size(app.summaryFrames{1}, 2);
             % 取得單張摘要影格的寬度
            times = linspace(0, app.summaryDur, 10);
            % 建立 10 個時間點，對應 10 張摘要影格的位置
    
          % 範圍外的格子塗黑
            for i = 1:10
                % 逐一檢查每一張摘要影格
                if times(i) < range(1) || times(i) > range(2)
                    % 如果第 i 張摘要影格的時間不在指定範圍內
                    x1 = (i-1)*W + 1; x2 = i*W;
                    % 計算第 i 張影格在 summary 圖中的起始與結束 x 座標
                    summary(:, x1:x2, :) = 0;
                    % 將該區塊塗黑
                    % 代表這個時間點不在有效範圍內
                end
            end
    
            % 當前播放位置畫黃框
            idx = round(currentTime / totalDur * 9) + 1;
            % 根據目前播放時間 currentTime 計算對應第幾張摘要影格
            % 因為總共有 10 張，所以索引範圍是 1 到 10
            idx = max(1, min(10, idx));
            % 將 idx 限制在 1 到 10 之間，避免超出範圍
            x1 = (idx-1)*W + 1;
            % 計算目前播放位置對應影格的起始 x 座標
            x2 = idx*W;
            % 計算目前播放位置對應影格的結束 x 座標
            thickness = 3;
            % 設定黃色框線的厚度為 3 pixels
            summary(1:thickness, x1:x2, 1) = 255;
            % 畫上方框線的紅色通道
            summary(1:thickness, x1:x2, 2) = 255;
            % 畫上方框線的綠色通道
            summary(1:thickness, x1:x2, 3) = 0;
            % 畫上方框線的藍色通道
            % RGB = [255, 255, 0]，所以是黃色
            summary(H-thickness+1:H, x1:x2, 1) = 255;
            % 畫下方框線的紅色通道
            summary(H-thickness+1:H, x1:x2, 2) = 255;
            % 畫下方框線的綠色通道
            summary(H-thickness+1:H, x1:x2, 3) = 0;
            % 畫下方框線的藍色通道
            summary(1:H, x1:x1+thickness-1, 1) = 255;
            % 畫左側框線的紅色通道
            summary(1:H, x1:x1+thickness-1, 2) = 255;
            % 畫左側框線的綠色通道
            summary(1:H, x1:x1+thickness-1, 3) = 0;
            % 畫左側框線的藍色通道
            summary(1:H, x2-thickness+1:x2, 1) = 255;
            % 畫右側框線的紅色通道
            summary(1:H, x2-thickness+1:x2, 2) = 255;
            % 畫右側框線的綠色通道
            summary(1:H, x2-thickness+1:x2, 3) = 0;
            % 畫右側框線的綠色通道
    
            app.Image2.ImageSource = summary;
            % 將處理後的摘要圖顯示在 app.Image2 上
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 這個函式會在 App 啟動後自動執行
            % 用來做初始化設定（資料、UI、預設值等）
            app.t = table('Size', [0, 2], ...
            'VariableTypes', {'string', 'double'}, ...
            'VariableNames', {'Filename', 'Duration'});
            % 建立一個空的 table（0 列 2 欄）
            %
            % 欄位說明：
            %   Filename ：影片檔名（string）
            %   Duration ：影片長度（double）
            %
            % 之後會用這個 table 來記錄使用者加入的影片清單

            % 載入 LUTs 目錄內容到清單
            if exist('LUTs', 'dir')
              % 檢查是否存在名為 "LUTs" 的資料夾
              files = dir('LUTs');
              % 讀取 LUTs 資料夾中的所有檔案與資料夾資訊
              names = {files.name};
              % 將檔案結構轉成 cell array，只保留名稱
              names = names(~ismember(names, {'.', '..'}));
              % 移除 "." 和 ".."（系統預設的當前與上層目錄）
              app.LUTsListBox.Items = names;
              % 將檔名列表放入 UI 的 ListBox 中
              % 使用者就可以在介面上選擇 LUT 檔案
            end

              % 隱藏大部分元件
              app.Image.Visible = 'off';
              app.Image2.Visible = 'off';
              app.LUTsListBox.Visible = 'off';
              app.LUTsLabel.Visible = 'off';
              app.RangeSlider.Visible = 'off';
              app.RangeLabel.Visible = 'off';
              app.Button.Visible = 'off';
              app.Button_2.Visible = 'off';
              app.UITable.Visible = 'off';
              app.OutputmergeLabel.Visible = 'off';
              app.SliderLabel.Visible = 'off';
              app.ListBoxLabel.Visible = 'off';
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            % 開啟影片檔
            [file, path] = uigetfile({'*.mp4;*.avi;*.mov', 'Video Files'});
            if isequal(file, 0), return; end
        
            app.v1 = VideoReader(fullfile(path, file));
            % 顯示 Cut 相關元件，隱藏 Merge 元件
            app.Image.Visible = 'on';
            app.Image2.Visible = 'on';
            app.LUTsListBox.Visible = 'on';
            app.LUTsLabel.Visible = 'on';
            app.RangeSlider.Visible = 'on';
            app.RangeLabel.Visible = 'on';
            app.Button.Visible = 'on';
            app.Button_2.Visible = 'off';
            app.UITable.Visible = 'off';
            app.OutputmergeLabel.Visible = 'off';
        
            % 顯示第一影格
            app.v1.CurrentTime = 0;
            app.im = readFrame(app.v1);
            app.Image.ImageSource = app.im;
        
            % 設定 RangeSlider 範圍為影片總長
            dur = app.v1.Duration;
            app.RangeSlider.Limits = [0, dur];
            app.RangeSlider.Value = [0, dur];
        
            % 載入 LUT
            loadSelectedLUT(app);
        
            % 製作 10 格視訊摘要
            makeVideoSummary(app, app.v1, app.Image2);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            % 定義 SaveMenuSelected 函式
            % 功能：當使用者點選 Save 選單時，將影片套用 LUT、淡入淡出、浮水印後輸出成 MP4
            if isempty(app.v1)
                % 如果 app.v1 是空的，代表尚未載入影片
                uialert(app.UIFigure, '請先開啟影片', 'Error'); return
                % 顯示錯誤訊息，並結束函式;
            end

            [file, path] = uiputfile({'*.mp4', 'MP4 Video'});
            % 開啟另存新檔視窗，讓使用者選擇輸出檔名與位置
            % 限制副檔名為 .mp4
            if isequal(file, 0), return; end
            % 如果使用者按取消，file 會等於 0
            % 此時直接結束函式
        
            range = app.RangeSlider.Value;
            % 取得 RangeSlider 選取的影片時間範圍
            % range(1)：開始時間
            % range(2)：結束時間
            vOut = VideoWriter(fullfile(path, file), 'MPEG-4');
            % 建立輸出影片物件
            % fullfile(path, file) 會組合完整輸出路徑
            vOut.FrameRate = app.v1.FrameRate;
            % 將輸出影片的幀率設定成原始影片的幀率
            if isempty(app.lut)
                % 如果尚未載入 LUT
                uialert(app.UIFigure, '請先選擇 LUT', 'Error');
                % 顯示錯誤訊息
                return;
                % 結束函式，不輸出影片
            end
            open(vOut);
            % 開啟 VideoWriter，準備開始寫入影片 frame
        
            app.v1.CurrentTime = range(1);
            fadeDur = 0.5;  % 加分：Fade In/Out 時長

            while hasFrame(app.v1) && app.v1.CurrentTime <= range(2)
                % 只要影片還有 frame，且目前時間還沒超過結束時間，就持續處理
                t_curr = app.v1.CurrentTime;
                % 記錄目前 frame 對應的時間
                frame = double(readFrame(app.v1)) / 255;
                % 讀取一張 frame
                % 並轉成 double，正規化到 [0,1]
                frame = applyLUT(app, frame, app.lut, app.lutSize);
                % 對目前 frame 套用 3D LUT 調色
    
                % Fade In
                if t_curr - range(1) < fadeDur
                    % 如果目前時間還在淡入區間內
                    frame = frame * ((t_curr - range(1)) / fadeDur);
                    % 將影像亮度從 0 慢慢增加到 1
                    % 越接近開始時間越暗
                end
                % Fade Out
                if range(2) - t_curr < fadeDur
                    % 如果目前時間進入淡出區間
                    frame = frame * ((range(2) - t_curr) / fadeDur);
                    % 將影像亮度從 1 慢慢降低到 0
                    % 越接近結束時間越暗
                end
    
                frame = addWatermark(app, frame);
                % 將浮水印加到目前 frame 上
                writeVideo(vOut, uint8(frame * 255));
                % 將 frame 從 [0,1] 轉回 uint8 的 [0,255]
                % 然後寫入輸出影片
            end

            close(vOut);
            uialert(app.UIFigure, '影片儲存完成！', 'Done');
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            % 定義 CloseMenuSelected 函式
            % 功能：當使用者點選 CloseMenu 時，清空目前載入的影片、影像、LUT 與暫存資料
            app.v1 = [];
            % 清空目前載入的影片物件
            app.im = [];
            % 清空目前影像資料
            app.lut = [];
            % 清空目前載入的 LUT 資料
            app.lutSize = [];
            % 清空 LUT 尺寸
            app.summaryFrames = [];
            % 清空影片摘要影格
            app.watermarkImage = [];    
            % 清空已產生的浮水印圖片
            % 下次需要浮水印時會重新產生
            startupFcn(app);
            % 重新執行初始化函式
            % 例如重新建立影片清單 table、重新載入 LUT 清單
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            % 定義 ExitMenuSelected 函式
            % 功能：當使用者點選 ExitMenu 時，關閉整個 App
            delete(app);
            % 刪除 app 物件，關閉 App 視窗並釋放資源
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            % 按鈕彈起時停止播放
            if ~app.Button.Value
                app.Button.Text = 'Play';
                return;     
            end
            % 防呆：未開影片
            if isempty(app.v1)
                app.Button.Value = false;
                uialert(app.UIFigure, '請先開啟影片', 'Error');
                return;
            end
            
            % 防呆：未載入 LUT
            if isempty(app.lut)
                app.Button.Value = false;
                uialert(app.UIFigure, '請先選擇 LUT', 'Error');
                return;
            end
            
            fadeDur = 0.5;
            % 設定淡入淡出時間為 0.5 秒
            range = app.RangeSlider.Value;
            % 取得使用者在 RangeSlider 選取的播放範圍
            % range(1)：開始時間
            % range(2)：結束時間
            app.v1.CurrentTime = range(1);
            % 將影片播放時間移到選取範圍的起點
            app.RangeSlider.Enable = 'off'; 
            % 播放中鎖定 RangeSlider
            % 避免播放過程中使用者更改範圍造成錯誤
            
            try
                % 播放前先對 Image2 套黑框
                if ~isempty(app.summaryFrames)
                    times = linspace(0, app.summaryDur, 10);
                    % 建立 10 個時間點，對應 10 張摘要影格
                    summary = cat(2, app.summaryFrames{:});
                    % 將 10 張摘要影格水平拼接成一張摘要圖
                    for i = 1:10
                        % 逐一檢查每張摘要影格是否在播放範圍內
                        if times(i) < range(1) || times(i) > range(2)
                            % 如果第 i 張摘要影格不在播放範圍內
                            W = size(app.summaryFrames{i}, 2);
                            % 取得第 i 張影格的寬度
                            x1 = (i-1)*W + 1; x2 = i*W;
                            % 計算第 i 張影格在摘要圖中的起始與結束 x 座標
                            summary(:, x1:x2, :) = 0;
                            % 將該區塊塗黑
                        end
                    end
                    app.Image2.ImageSource = summary;
                end
    
                while app.Button.Value && hasFrame(app.v1) && app.v1.CurrentTime <= range(2)
                    % 播放迴圈
                    % 條件：
                    % 1. 播放按鈕仍然是按下狀態
                    % 2. 影片還有 frame 可以讀
                    % 3. 目前時間還沒超過使用者選取的結束時間
                    t_curr = app.v1.CurrentTime;
                    % 記錄目前影片時間
                    frame = double(readFrame(app.v1)) / 255;
                    % 讀取目前 frame
                    % 並轉成 double，正規化到 [0,1]
                    frame = applyLUT(app, frame, app.lut, app.lutSize);
                    % 對目前 frame 套用 LUT 調色
                    % Fade In
                    if t_curr - range(1) < fadeDur
                        % 如果目前時間在淡入區間內
                        frame = frame * ((t_curr - range(1)) / fadeDur);
                        % 讓畫面從黑慢慢變亮
                    end
                    % Fade Out
                    if range(2) - t_curr < fadeDur
                        % 如果目前時間在淡出區間內
                        frame = frame * max(0, (range(2) - t_curr) / fadeDur);
                        % 讓畫面從亮慢慢變黑
                        % max(0, ...) 是為了避免倍率變成負數
                    end
                    frame = addWatermark(app, frame);
                    app.Image.ImageSource = uint8(frame * 255);
                    app.Button.Text = num2str(app.v1.CurrentTime, '%.2f');
                
                    % 加分：同步標記 Image2 黃框
                    highlightSummaryFrame(app, app.v1.CurrentTime, app.v1.Duration, range);
                    % 在摘要圖上用黃色框標記目前播放時間對應的縮圖
                    drawnow;
                end
            catch ME
                uialert(app.UIFigure, ['播放錯誤：' ME.message], 'Error');
            end
            % 播放結束，還原狀態
            app.Button.Value = false;
            % 將播放按鈕狀態改回未按下
            app.Button.Text = 'Play';
            % 將按鈕文字改回 Play
            app.RangeSlider.Enable = 'on';      
            % 播放結束後重新啟用 RangeSlider
            
        end

        % Value changed function: LUTsListBox
        function LUTsListBoxValueChanged(app, event)
            % 定義 LUTsListBoxValueChanged 函式
            % 功能：當使用者在 LUT 清單中選擇新的 LUT 時，載入 LUT 並更新預覽畫面
            % 載入新選取的 LUT
            loadSelectedLUT(app);
            % 呼叫 loadSelectedLUT，讀取目前選到的 .cube LUT 檔案

            % 即時預覽套用 LUT 後的效果
            if ~isempty(app.im) && ~isempty(app.lut)
                % 呼叫 loadSelectedLUT，讀取目前選到的 .cube LUT 檔案
                preview = applyLUT(app, app.im, app.lut, app.lutSize);
                % 將目前影像套用 LUT，產生預覽結果
                preview = addWatermark(app, preview);
                % 在預覽影像上加上浮水印
                app.Image.ImageSource = uint8(preview * 255);
                % 將預覽影像從 [0,1] 轉回 uint8 的 [0,255]
                % 並顯示在 app.Image 上
            end    
        end

        % Menu selected function: WatermarkMenu
        function WatermarkMenuSelected(app, event)
            if isempty(app.watermark)
                defaults = {'NTUST 2026'; '20'};      % 第一次打開用這個當預設
            else
                defaults = app.watermark;             % 已設定過就延用上次
            end
            answer = inputdlg({'Watermark', 'FontSize'}, 'Watermark', 1, defaults);
            % 開啟輸入對話框
            % 讓使用者輸入：
            %   Watermark：浮水印文字
            %   FontSize ：字體大小
            if isempty(answer), return; end
            % 如果使用者按取消，answer 會是空的
            % 此時直接結束函式
            app.watermark = answer;
            % 將使用者輸入的浮水印設定存到 app.watermark
            app.watermarkImage = [];
            % 清空舊的浮水印圖片
            % 因為文字或字體大小可能已改變，需要重新產生
    
            if ~isempty(app.im)
                frame = double(app.im) / 255;
                if ~isempty(app.lut)
                    % 如果目前已經載入 LUT
                    frame = applyLUT(app, frame, app.lut, app.lutSize);
                    % 先對影像套用 LUT
                end
                frame = addWatermark(app, frame);
                % 將新的浮水印加到影像上
                app.Image.ImageSource = uint8(frame * 255);
                % 將結果轉回 uint8 並顯示在主要 Image 元件上
            end
        end

        % Menu selected function: OpenMenu_2
        function OpenMenu_2Selected(app, event)
            % 定義 OpenMenu_2Selected 函式
            % 功能：選取一支或多支影片，加入合併清單，並執行影片合併
            [file, path] = uigetfile({'*.mp4;*.avi;*.mov', 'Video Files'}, ...
            'Select Video', 'MultiSelect', 'on');
            % 開啟選檔視窗
            % 允許選取 mp4、avi、mov 影片
            % MultiSelect 設為 on，代表可以一次選多個檔案
            if isequal(file, 0), return; end
            % 如果使用者按取消，file 會等於 0
            % 此時直接結束函式
            if ischar(file), file = {file}; end  
            % 如果使用者只選一個檔案，file 會是 char
            % 這裡將它轉成 cell，方便後面用同一種方式處理
             
            % 顯示 Merge 相關元件
            app.Image.Visible = 'on';
            app.Image2.Visible = 'on';
            app.UITable.Visible = 'on';
            app.Button_2.Visible = 'on';
            app.OutputmergeLabel.Visible = 'on';
            app.RangeSlider.Visible = 'off';
            app.RangeLabel.Visible = 'off';
            app.Button.Visible = 'off';
            app.LUTsListBox.Visible = 'off';
            app.LUTsLabel.Visible = 'off';
        
            % 將選取的影片加入表列
            for i = 1:numel(file)
                vr = VideoReader(fullfile(path, file{i}));
                % 建立 VideoReader 物件，用來讀取影片資訊
                newRow = {string(fullfile(path, file{i})), vr.Duration};
                % 建立新的一列資料
                % 第一欄：影片完整路徑
                % 第二欄：影片長度
                app.t = [app.t; newRow];
            end
            app.UITable.Data = app.t;
            % 將影片清單顯示在 UITable 上
        
            % 串接影片並更新摘要
            mergeVideos(app);
            % 呼叫 mergeVideos，將清單中的影片合併，並產生摘要圖
        end

        % Menu selected function: CloseMenu_2
        function CloseMenu_2Selected(app, event)
            app.t = table('Size', [0,2], ...
            'VariableTypes', {'string','double'}, ...
            'VariableNames', {'Filename','Duration'});
            % 重新建立一個空的影片清單 table
            % 欄位包含：
            %   Filename ：影片路徑
            %   Duration ：影片長度
            app.summaryFrames = [];    
            % 清空合併影片摘要影格
            app.mergeFile = 'merge.mp4'
            % 將合併影片檔名重設為預設值 merge.mp4
            % 注意：原始程式這行少了分號，執行時會在 Command Window 顯示結果
            startupFcn(app);
            % 重新執行初始化函式
            % 例如重新載入 LUT 清單、重建 table 等
        end

        % Menu selected function: ExitMenu_2
        function ExitMenu_2Selected(app, event)
            % 定義 ExitMenu_2Selected 函式
            % 功能：關閉整個 App
            delete(app);
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
            % 定義 CloseMenu_2Selected 函式
            % 功能：清空合併影片模式的資料，並重新初始化 App
            if ~app.Button_2.Value
                % 如果按鈕被取消選取，代表使用者停止播放
                app.Button_2.Text = 'Play';
                return;
            end
 
            % 驗證：檔案必須存在 + 大小合理 + 能讀
            if isempty(app.mergeFile) || ~exist(app.mergeFile, 'file')
                % 如果 mergeFile 是空的，或是檔案不存在
                app.Button_2.Value = false;
                % 將按鈕狀態改回未按下
                uialert(app.UIFigure, '請先用 Merge → Open 合併影片', '無 merge 檔');
                return;
            end
    
            info = dir(app.mergeFile);
            % 取得合併影片檔案資訊，例如檔案大小
            if info.bytes < 1024
                % 如果檔案小於 1024 bytes，通常代表影片可能損毀或沒有正常輸出
                app.Button_2.Value = false;
                uialert(app.UIFigure, ...
                sprintf('%s 損毀（%d bytes）。請刪除後重新 Merge', app.mergeFile, info.bytes), ...
                '檔案損毀');
                return;
            end
    
            try
                % 嘗試建立 VideoReader 讀取合併後的影片
                vMerge = VideoReader(app.mergeFile);
                % 建立合併影片的 VideoReader 物件
            catch ME
                % 如果 VideoReader 無法讀取影片
                app.Button_2.Value = false;
                % 將按鈕狀態改回未按下
                uialert(app.UIFigure, ...
                sprintf('無法讀取 %s\n%s\n請刪除此檔後重新 Merge', app.mergeFile, ME.message), ...
                'VideoReader 錯誤');
                return;
            end
    
            try
                while app.Button_2.Value && hasFrame(vMerge)
                    % 播放迴圈
                    % 條件：
                    % 1. Button_2 仍然是按下狀態
                    % 2. 合併影片還有 frame 可以讀取
                    try
                        frame = readFrame(vMerge);
                        % 讀取合併影片中的一個 frame
                    catch
                        break;
                    end
                    app.Image.ImageSource = frame;
                    % 將目前讀到的 frame 顯示在主要 Image 元件上
                    app.Button_2.Text = num2str(vMerge.CurrentTime, '%.2f');
                    % 將 Button_2 的文字暫時顯示為目前播放時間
                    % %.2f 代表顯示到小數點後兩位
                    highlightSummaryFrame(app, vMerge.CurrentTime, vMerge.Duration, [0, vMerge.Duration]);
                    % 在摘要圖上用黃色框標記目前播放位置
                    % 因為是播放整支合併影片，所以 range 設為 [0, vMerge.Duration]
                    drawnow;
                    % 強制更新 UI，讓畫面與按鈕文字即時刷新
                end
            catch ME
                % 如果播放過程發生其他錯誤
                uialert(app.UIFigure, ME.message, 'Play Error');
            end
            app.Button_2.Value = false;
            % 播放結束後，將按鈕狀態改回未按下
            app.Button_2.Text = 'Play';
            % 將按鈕文字改回 Play
            
        end

        % Value changed function: RangeSlider
        function RangeSliderValueChanged(app, event)
            % 定義 RangeSliderValueChanged 函式
            % 功能：當使用者調整影片範圍時，更新預覽畫面與摘要圖
            if isempty(app.v1), return; end
            % 如果尚未載入影片，直接結束函式
            range = app.RangeSlider.Value;
            % 取得 RangeSlider 目前選取的時間範圍
            % range(1)：開始時間
            % range(2)：結束時間
            app.v1.CurrentTime = max(0, range(1));
            if hasFrame(app.v1)
                % 如果目前時間點有可讀取的 frame
                frame = double(readFrame(app.v1)) / 255;
                % 讀取目前 frame，轉成 double，並正規化到 [0,1]
                if ~isempty(app.lut)
                    % 如果已經載入 LUT
                    frame = applyLUT(app, frame, app.lut, app.lutSize);
                    % 套用 LUT 調色
                end
                frame = addWatermark(app, frame);
                % 加上浮水印
                app.Image.ImageSource = uint8(frame * 255);
                % 將結果轉回 uint8，並顯示在主要 Image 元件上
            end
            % Image2：範圍外的格子變黑
            if ~isempty(app.summaryFrames)
                % 如果已經有影片摘要影格
                times = linspace(0, app.summaryDur, 10);
                % 建立 10 個摘要影格對應的時間點
                summary = cat(2, app.summaryFrames{:});
                % 將 10 張摘要影格水平拼接成一張摘要圖
                for i = 1:10
                    % 逐一檢查每一張摘要影格
                    if times(i) < range(1) || times(i) > range(2)
                        % 如果第 i 張影格不在 RangeSlider 選取範圍內
                        W = size(app.summaryFrames{i}, 2);
                        % 取得第 i 張摘要影格的寬度
                        x1 = (i-1)*W + 1;
                        % 計算第 i 張影格在 summary 圖中的起始 x 座標
                        x2 = i*W;
                        % 計算第 i 張影格在 summary 圖中的結束 x 座標
                        summary(:, x1:x2, :) = 0;
                        % 將範圍外的摘要影格塗黑
                    end
                end
                app.Image2.ImageSource = summary;
                % 將更新後的摘要圖顯示在 Image2
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CutMenu = uimenu(app.UIFigure);
            app.CutMenu.Text = 'Cut';
            app.OpenMenu = uimenu(app.CutMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Tooltip = {'開啟檔案'};
            app.OpenMenu.Accelerator = 'O';
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.CutMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Tooltip = {'儲存檔案'};
            app.SaveMenu.Accelerator = 'S';
            app.SaveMenu.Text = 'Save';
            app.CloseMenu = uimenu(app.CutMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Tooltip = {'關閉檔案'};
            app.CloseMenu.Accelerator = 'X';
            app.CloseMenu.Text = 'Close';
            app.ExitMenu = uimenu(app.CutMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Tooltip = {'離開'};
            app.ExitMenu.Accelerator = 'E';
            app.ExitMenu.Text = 'Exit';
            app.MergeMenu = uimenu(app.UIFigure);
            app.MergeMenu.Text = 'Merge';
            app.OpenMenu_2 = uimenu(app.MergeMenu);
            app.OpenMenu_2.MenuSelectedFcn = createCallbackFcn(app, @OpenMenu_2Selected, true);
            app.OpenMenu_2.Tooltip = {'開啟檔案'};
            app.OpenMenu_2.Accelerator = 'I';
            app.OpenMenu_2.Text = 'Open';
            app.CloseMenu_2 = uimenu(app.MergeMenu);
            app.CloseMenu_2.MenuSelectedFcn = createCallbackFcn(app, @CloseMenu_2Selected, true);
            app.CloseMenu_2.Tooltip = {'關閉檔案'};
            app.CloseMenu_2.Accelerator = 'D';
            app.CloseMenu_2.Text = 'Close';
            app.ExitMenu_2 = uimenu(app.MergeMenu);
            app.ExitMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ExitMenu_2Selected, true);
            app.ExitMenu_2.Tooltip = {'離開'};
            app.ExitMenu_2.Text = 'Exit';
            app.WatermarkMenu = uimenu(app.UIFigure);
            app.WatermarkMenu.MenuSelectedFcn = createCallbackFcn(app, @WatermarkMenuSelected, true);
            app.WatermarkMenu.Text = 'Watermark';
            app.Image = uiimage(app.UIFigure);
            app.Image.Tooltip = {'影片預覽'};
            app.Image2 = uiimage(app.UIFigure);
            app.LUTsLabel = uilabel(app.UIFigure);
            app.LUTsLabel.Text = 'LUTs';
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.LUTsListBox = uilistbox(app.UIFigure);
            app.LUTsListBox.ValueChangedFcn = createCallbackFcn(app, @LUTsListBoxValueChanged, true);
            app.LUTsListBox.Tooltip = {'選擇 LUT 調色檔'};
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.RangeSlider = uislider(app.UIFigure, 'range');
            app.RangeSlider.ValueChangedFcn = createCallbackFcn(app, @RangeSliderValueChanged, true);
            app.RangeSlider.Tooltip = {'拖拉設定裁切範圍'};
            app.RangeLabel = uilabel(app.UIFigure);
            app.RangeLabel.Text = 'Range';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Tooltip = {'播放／停止（Cut 模式）'};
            app.Button.Text = 'Play';
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Tooltip = {'播放／停止（Merge 模式）'};
            app.Button_2.Text = 'Play';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Filename'; 'Duration'};
            app.UITable.RowName = {};
            app.UITable.Tooltip = {'待合併影片清單'};
            app.OutputmergeLabel = uilabel(app.UIFigure);
            app.OutputmergeLabel.Tooltip = {'合併輸出檔名'};
            app.OutputmergeLabel.Text = 'Output: merge.mp4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HW2_M11415015

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end