classdef app24_vid_proc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        FileMenu        matlab.ui.container.Menu
        OpenMenu        matlab.ui.container.Menu
        SaveAsMenu      matlab.ui.container.Menu
        ExitMenu        matlab.ui.container.Menu
        ProcessMenu     matlab.ui.container.Menu
        NegativeMenu    matlab.ui.container.Menu
        LRFlipMenu      matlab.ui.container.Menu
        DarkerMenu      matlab.ui.container.Menu
        DifferenceMenu  matlab.ui.container.Menu
        GridLayout      matlab.ui.container.GridLayout
        LeftPanel       matlab.ui.container.Panel
        Button          matlab.ui.control.StateButton
        Label           matlab.ui.control.Label
        Image           matlab.ui.control.Image
        RightPanel      matlab.ui.container.Panel
        Label2          matlab.ui.control.Label
        Image_2         matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        vidObj %視訊物件
        im %當下的畫面
        im_prev %前一幀的畫面
        p = 1 %影像處理選項
    end
    
    methods (Access = private)
        
        function results = func(app) %影像處理
            switch app.p %選項
                case 1 %反白
                    results = 255- app.im;
                    app.Label2.Text = 'Negative';
                case 2 %左右翻轉
                    results = fliplr(app.im);
                    app.Label2.Text = 'LR Flip';
                case 3 %變暗
                    results = app.im - 64;
                    app.Label2.Text = 'Darker';
                case 4 %影像時間差異
                    results = imabsdiff(app.im,app.im_prev); %跟前畫面的差異
                    app.im_prev = app.im;
                    app.Label2.Text = 'Temporal Difference';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.Image.ScaleMethod = 'stretch';
            app.Image_2.ScaleMethod = 'stretch';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {484, 484};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {547, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            %讀取視訊路徑檔名
            [filename, pathname]  = uigetfile({'*.mp4';'*.avi';'*.mpg'},'read a video file'); 
            %建立是視訊物件
            app.vidObj = VideoReader([pathname filename]);
            app.im = read(app.vidObj, 1); %讀取第一幀的影像
            app.Image.ImageSource = app.im; %顯示到Image圖框
            app.im_prev = app.im; %暫存(用於比較影像差異)
            app.Image_2.ImageSource = func(app); 
            app.Button.Icon = 'down.jpg';
            app.Label.Text = '0';
            figure(app.UIFigure)
        end

        % Callback function: ExitMenu, UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Menu selected function: NegativeMenu
        function NegativeMenuSelected(app, event)
            app.p = 1;
            app.Image_2.ImageSource = func(app);
        end

        % Menu selected function: LRFlipMenu
        function LRFlipMenuSelected(app, event)
            app.p = 2;
            app.Image_2.ImageSource = func(app);
        end

        % Menu selected function: DarkerMenu
        function DarkerMenuSelected(app, event)
            app.p = 3;
            app.Image_2.ImageSource = func(app);
        end

        % Callback function
        function ButtonPushed(app, event)
            read(app.vidObj,1);
            app.Button.Icon = 'pause.jpg';

            while hasFrame(app.vidObj)
                app.im = readFrame(app.vidObj);
                pause(1/app.vidObj.FrameRate)
                app.Label.Text = num2str(app.vidObj.CurrentTime);
                app.Image.ImageSource = app.im;
                app.Image_2.ImageSource = func(app);
            end
            app.Button.Icon = 'down.jpg';
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            if value
                read(app.vidObj,1);
                app.Button.Icon = 'pause.jpg';
                
                while hasFrame(app.vidObj)
                    if app.Button.Value == 0
                        break;
                    end
                    app.im = readFrame(app.vidObj);
                    pause(1/app.vidObj.FrameRate)
                    app.Label.Text = num2str(app.vidObj.CurrentTime,'%.2f');
                    app.Image.ImageSource = app.im;
                    app.Image_2.ImageSource = func(app);
                end
            else
                app.Button.Icon = 'down.jpg';
            end
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            [filename, pathname] = uiputfile({'*.mp4'},'Save a video file');
            vidObj2 = VideoWriter([pathname filename],'MPEG-4'); %建立待寫入的mp4視訊物件
            vidObj2.Quality = 90; % default quality = 75
            open(vidObj2) %開啟視訊物件
            read(app.vidObj,1); %讀第一幀
            
            while hasFrame(app.vidObj) %如果還有畫面，就寫入影像
                im2 = readFrame(app.vidObj); %讀取下一幀
                writeVideo(vidObj2, im2); %將這一幀寫入 vidObj2 視訊
            end
            close(vidObj2); %關閉 vidObj2 視訊物件        
        end

        % Menu selected function: DifferenceMenu
        function DifferenceMenuSelected(app, event)
            app.p = 4;
            app.Image_2.ImageSource = func(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.ProcessMenu = uimenu(app.UIFigure);
            app.ProcessMenu.Text = 'Process';
            app.NegativeMenu = uimenu(app.ProcessMenu);
            app.NegativeMenu.MenuSelectedFcn = createCallbackFcn(app, @NegativeMenuSelected, true);
            app.NegativeMenu.Text = 'Negative';
            app.LRFlipMenu = uimenu(app.ProcessMenu);
            app.LRFlipMenu.MenuSelectedFcn = createCallbackFcn(app, @LRFlipMenuSelected, true);
            app.LRFlipMenu.Text = 'LR Flip';
            app.DarkerMenu = uimenu(app.ProcessMenu);
            app.DarkerMenu.MenuSelectedFcn = createCallbackFcn(app, @DarkerMenuSelected, true);
            app.DarkerMenu.Text = 'Darker';
            app.DifferenceMenu = uimenu(app.ProcessMenu);
            app.DifferenceMenu.MenuSelectedFcn = createCallbackFcn(app, @DifferenceMenuSelected, true);
            app.DifferenceMenu.Text = 'Difference';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.LeftPanel);
            app.Label = uilabel(app.LeftPanel);
            app.Button = uibutton(app.LeftPanel, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Icon = 'pause.jpg';
            app.Button.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image_2 = uiimage(app.RightPanel);
            app.Label2 = uilabel(app.RightPanel);
            app.Label2.Text = 'Label2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app24_vid_proc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end